classdef check_model_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        Slider_2     matlab.ui.control.Slider
        ALabel       matlab.ui.control.Label
        nLabel       matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        DrawButton   matlab.ui.control.Button
        valueALabel  matlab.ui.control.Label
        valuenLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % SD
            
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            rssi_to_dist = @(r,A,n) power(10, (A - r) / 10 / n);
            A = app.Slider.Value;
            n = app.Slider_2.Value;
            rssi_x = -90:0.5:-30;
            dist_y = rssi_to_dist(rssi_x,A,n);
            p1 = plot(app.UIAxes,rssi_x,dist_y);
            p1.LineWidth = 1.5;
            p1.Color = [255,153,51]/255;
            % p1.Marker = 'o';
            % p1.MarkerSize = 6;
            % p1.MarkerEdgeColor = 'g';
            
            % ---------------------------------------------------- %
            try
                close('model-tool');
            catch
            end
            f1 = figure('name','model-tool','color','w','Position',[800,100,800,600]); % Description
            datacursormode(f1,'on');
            gca_f1 = gca(f1);
            
            
            p2 = plot(gca_f1,rssi_x,dist_y);
            p2.LineWidth = 1.5;
            p2.Color = [255,153,51]/255;
            p2.Marker = 'o';
            p2.MarkerSize = 6;
            p2.MarkerEdgeColor = 'g';
            title(gca_f1, '对数模型')
            xlabel(gca_f1, 'RSSI/dBm')
            ylabel(gca_f1, 'dist/m')
            gca_f1.FontName = 'Bookman';
            gca_f1.FontSize = 14;
            gca_f1.FontWeight = 'bold';
            gca_f1.Box = 'on';
            gca_f1.XGrid = 'on';
            gca_f1.YGrid = 'on';
            gca_f1.TitleFontWeight = 'bold';
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            value = sprintf('%.2f',value);
            app.valuenLabel.Text = value;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            value = sprintf('%.2f',value);
            app.valueALabel.Text = value;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = sprintf('%.2f',changingValue);
            app.valueALabel.Text = changingValue;
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue = sprintf('%.2f',changingValue);
            app.valuenLabel.Text = changingValue;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear global f1;
            delete(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '对数模型')
            xlabel(app.UIAxes, 'RSSI/dBm')
            ylabel(app.UIAxes, 'dist/m')
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-60 -1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = -18;
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [1.5 6.5];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 3;
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.valueALabel = uilabel(app.UIFigure);
            app.valueALabel.Text = 'value-A';
            app.valuenLabel = uilabel(app.UIFigure);
            app.valuenLabel.Text = 'value-n';
        end
    end

    methods (Access = public)

        % Construct app
        function app = check_model_tool

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end